classdef runWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Step4RUNPanel          matlab.ui.container.Panel
        GridLayout             matlab.ui.container.GridLayout
        RunButton              matlab.ui.control.Button
        SavePanel              matlab.ui.container.Panel
        SaveResultsLabel       matlab.ui.control.Label
        SaveNoCheckBox         matlab.ui.control.CheckBox
        SaveYesCheckBox        matlab.ui.control.CheckBox
        PlotsPanel             matlab.ui.container.Panel
        PlotLevelReturnsLabel  matlab.ui.control.Label
        PlotNoCheckBox         matlab.ui.control.CheckBox
        PlotYesCheckBox        matlab.ui.control.CheckBox
        MCMCPanel              matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        BurninPanel            matlab.ui.container.Panel
        BurnInValue            matlab.ui.control.EditField
        NIterationsPanel       matlab.ui.container.Panel
        IterationValue         matlab.ui.control.EditField
        NChainsPanel           matlab.ui.container.Panel
        ChainsValue            matlab.ui.control.EditField
    end

    
    properties (Access = private)
        FinalData % Property to store the complete data package
        RunSettingsMatrix
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Set default values in the UI
            app.ChainsValue.Value = '5';
            app.IterationValue.Value = '15000';
            app.BurnInValue.Value = '10000';
            app.SaveYesCheckBox.Value = true;
            app.PlotYesCheckBox.Value = true;
            app.SaveNoCheckBox.Enable = 'off';
            app.PlotNoCheckBox.Enable = 'off';
       
            
            app.UIFigure.Name = "TRUE";

            uiwait(app.UIFigure);
        end

        % Value changed function: ChainsValue
        function ChainsValueValueChanged(app, event)
            value = app.ChainsValue.Value;
            
        end

        % Value changed function: IterationValue
        function IterationValueValueChanged(app, event)
            value = app.IterationValue.Value;
            
        end

        % Value changed function: BurnInValue
        function BurnInValueValueChanged(app, event)
            value = app.BurnInValue.Value;
            
        end

        % Value changed function: PlotYesCheckBox
        function PlotYesCheckBoxValueChanged(app, event)
             if app.PlotYesCheckBox.Value, app.PlotNoCheckBox.Enable = 'off'; else, app.PlotNoCheckBox.Enable = 'on'; end
       
        end

        % Value changed function: PlotNoCheckBox
        function PlotNoCheckBoxValueChanged(app, event)
            if app.PlotNoCheckBox.Value, app.PlotYesCheckBox.Enable = 'off'; else, app.PlotYesCheckBox.Enable = 'on'; end
        
        end

        % Value changed function: SaveYesCheckBox
        function SaveYesCheckBoxValueChanged(app, event)
             if app.SaveYesCheckBox.Value, app.SaveNoCheckBox.Enable = 'off'; else, app.SaveNoCheckBox.Enable = 'on'; end
        
        end

        % Value changed function: SaveNoCheckBox
        function SaveNoCheckBoxValueChanged(app, event)
            if app.SaveNoCheckBox.Value, app.SaveYesCheckBox.Enable = 'off'; else, app.SaveYesCheckBox.Enable = 'on'; end
        
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
             % --- NEW: Create settings matrix and assign to workspace ---
            app.RunSettingsMatrix = { ...
                str2double(app.ChainsValue.Value),     'Chains'; ...
                str2double(app.IterationValue.Value),  'Iterations'; ...
                str2double(app.BurnInValue.Value),     'BurnIn'; ...
                app.PlotYesCheckBox.Value,             'GeneratePlots'; ...
                app.SaveYesCheckBox.Value,             'SaveResults' ...
            };
            assignin('base', 'Run_Settings_Matrix', app.RunSettingsMatrix);
             uiresume(app.UIFigure);
             delete(app.UIFigure);
            % --- End of new section ---
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Step4RUNPanel = uipanel(app.UIFigure);
            app.Step4RUNPanel.Title = 'Step 4 : RUN';
            app.GridLayout = uigridlayout(app.Step4RUNPanel);
            app.MCMCPanel = uipanel(app.GridLayout);
            app.MCMCPanel.Title = 'MCMC';
            app.GridLayout2 = uigridlayout(app.MCMCPanel);
            app.NChainsPanel = uipanel(app.GridLayout2);
            app.NChainsPanel.Title = 'N. Chains';
            app.ChainsValue = uieditfield(app.NChainsPanel, 'text');
            app.ChainsValue.ValueChangedFcn = createCallbackFcn(app, @ChainsValueValueChanged, true);
            app.NIterationsPanel = uipanel(app.GridLayout2);
            app.NIterationsPanel.Title = 'N. Iterations';
            app.IterationValue = uieditfield(app.NIterationsPanel, 'text');
            app.IterationValue.ValueChangedFcn = createCallbackFcn(app, @IterationValueValueChanged, true);
            app.BurninPanel = uipanel(app.GridLayout2);
            app.BurninPanel.Title = 'Burn-in';
            app.BurnInValue = uieditfield(app.BurninPanel, 'text');
            app.BurnInValue.ValueChangedFcn = createCallbackFcn(app, @BurnInValueValueChanged, true);
            app.PlotsPanel = uipanel(app.GridLayout);
            app.PlotsPanel.Title = 'Plots';
            app.PlotYesCheckBox = uicheckbox(app.PlotsPanel);
            app.PlotYesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotYesCheckBoxValueChanged, true);
            app.PlotYesCheckBox.Text = 'Yes';
            app.PlotNoCheckBox = uicheckbox(app.PlotsPanel);
            app.PlotNoCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotNoCheckBoxValueChanged, true);
            app.PlotNoCheckBox.Text = 'No';
            app.PlotLevelReturnsLabel = uilabel(app.PlotsPanel);
            app.PlotLevelReturnsLabel.Text = 'Plot Level Returns?';
            app.SavePanel = uipanel(app.GridLayout);
            app.SavePanel.Title = 'Save';
            app.SaveYesCheckBox = uicheckbox(app.SavePanel);
            app.SaveYesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveYesCheckBoxValueChanged, true);
            app.SaveYesCheckBox.Text = 'Yes';
            app.SaveNoCheckBox = uicheckbox(app.SavePanel);
            app.SaveNoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveNoCheckBoxValueChanged, true);
            app.SaveNoCheckBox.Text = 'No';
            app.SaveResultsLabel = uilabel(app.SavePanel);
            app.SaveResultsLabel.Text = 'Save Results?';
            app.RunButton = uibutton(app.GridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = runWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end