classdef third_gev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Step3PriorInformationGEVPanel  matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        ContinueButton                 matlab.ui.control.Button
        ShapePanel                     matlab.ui.container.Panel
        Grid_Shape                     matlab.ui.container.GridLayout
        Prior_Param_Shape              matlab.ui.container.Panel
        Grid_Shape_Param               matlab.ui.container.GridLayout
        Shape_Params2                  matlab.ui.control.EditField
        Shape_Params1                  matlab.ui.control.EditField
        Prior_Distribution_Shape       matlab.ui.container.Panel
        Grid_Shape_Distribution        matlab.ui.container.GridLayout
        GammaCheckBox_Shape            matlab.ui.control.CheckBox
        NormalCheckBox_Shape           matlab.ui.control.CheckBox
        UniformCheckBox_Shape          matlab.ui.control.CheckBox
        ScalePanel                     matlab.ui.container.Panel
        Grid_Scale                     matlab.ui.container.GridLayout
        PriorParametersPanel_Scale     matlab.ui.container.Panel
        GridLayout3_2                  matlab.ui.container.GridLayout
        Scale_Params2                  matlab.ui.control.EditField
        Scale_Params1                  matlab.ui.control.EditField
        PriorDistributionPanel_Scale   matlab.ui.container.Panel
        GridLayout2_2                  matlab.ui.container.GridLayout
        GammaCheckBox_Scale            matlab.ui.control.CheckBox
        NormalCheckBox_Scale           matlab.ui.control.CheckBox
        UniformCheckBox_Scale          matlab.ui.control.CheckBox
        LocationPanel                  matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        PriorParametersPanel_Location  matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        Location_Params2               matlab.ui.control.EditField
        Location_Params1               matlab.ui.control.EditField
        PriorDistributionPanel_Location  matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        GammaCheckBox_Location         matlab.ui.control.CheckBox
        NormalCheckBox_Location        matlab.ui.control.CheckBox
        UniformCheckBox_Location       matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
       
        GEV_SettingsMatrix 
    end
     

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
            app.GEV_SettingsMatrix = { ...
            'norm', 'norm', 'norm';   % Distribution defaults
            0,       0,       0';        % Param1 defaults
            1000,       1000,       0.3};       % Param2 defaults


           %location
            app.Location_Params1.Value = '0';
            app.Location_Params2.Value = '1000';
            
    
            % Scale
            app.Scale_Params1.Value = '0';
            app.Scale_Params2.Value = '1000';
          
            % Shape
            app.Shape_Params1.Value = '0';
            app.Shape_Params2.Value = '0.3';
            app.UIFigure.Name ="TRUE";
            uiwait(app.UIFigure);

           
        end

        % Value changed function: UniformCheckBox_Location
        function UniformCheckBox_LocationValueChanged(app, event)
            if app.UniformCheckBox_Location.Value
                app.NormalCheckBox_Location.Enable = 'off';
                app.GammaCheckBox_Location.Enable = 'off';
                 app.GEV_SettingsMatrix{1,1} = 'unif'; 
            else
                app.NormalCheckBox_Location.Enable = 'on';
                app.GammaCheckBox_Location.Enable = 'on';
               
            end
        end

        % Value changed function: NormalCheckBox_Location
        function NormalCheckBox_LocationValueChanged(app, event)
            if app.NormalCheckBox_Location.Value
                app.UniformCheckBox_Location.Enable = 'off';
                app.GammaCheckBox_Location.Enable = 'off';
                app.GEV_SettingsMatrix{1,1} = 'norm';
            else
                app.UniformCheckBox_Location.Enable = 'on';
                app.GammaCheckBox_Location.Enable = 'on';
               
            end
        end

        % Value changed function: GammaCheckBox_Location
        function GammaCheckBox_LocationValueChanged(app, event)
            if app.GammaCheckBox_Location.Value
                app.UniformCheckBox_Location.Enable = 'off';
                app.NormalCheckBox_Location.Enable = 'off';
                 app.GEV_SettingsMatrix{1,1} = 'gam';
            else
                app.UniformCheckBox_Location.Enable = 'on';
                app.NormalCheckBox_Location.Enable = 'on';
             
            end
        end

        % Value changed function: UniformCheckBox_Scale
        function UniformCheckBox_ScaleValueChanged(app, event)
           if app.UniformCheckBox_Scale.Value
                app.NormalCheckBox_Scale.Enable = 'off';
                app.GammaCheckBox_Scale.Enable = 'off';
                 app.GEV_SettingsMatrix{1,2} = 'unif';
            else
                app.NormalCheckBox_Scale.Enable = 'on';
                app.GammaCheckBox_Scale.Enable = 'on';
               
            end
        end

        % Value changed function: NormalCheckBox_Scale
        function NormalCheckBox_ScaleValueChanged(app, event)
            if app.NormalCheckBox_Scale.Value
                app.UniformCheckBox_Scale.Enable = 'off';
                app.GammaCheckBox_Scale.Enable = 'off';
                 app.GEV_SettingsMatrix{1,2} = 'norm';
            else
                app.UniformCheckBox_Scale.Enable = 'on';
                app.GammaCheckBox_Scale.Enable = 'on';
             
            end
        end

        % Value changed function: GammaCheckBox_Scale
        function GammaCheckBox_ScaleValueChanged(app, event)
             if app.GammaCheckBox_Scale.Value
                 app.UniformCheckBox_Scale.Enable = 'off';
                 app.NormalCheckBox_Scale.Enable = 'off';
                 app.GEV_SettingsMatrix{1,2} = 'gam';
             else
                 app.UniformCheckBox_Scale.Enable = 'on';
                 app.NormalCheckBox_Scale.Enable = 'on';
                
             end
        end

        % Value changed function: UniformCheckBox_Shape
        function UniformCheckBox_ShapeValueChanged(app, event)
            if app.UniformCheckBox_Shape.Value
                app.NormalCheckBox_Shape.Enable = 'off';
                app.GammaCheckBox_Shape.Enable = 'off';
                 app.GEV_SettingsMatrix{1,3} = 'unif';
            else
                app.NormalCheckBox_Shape.Enable = 'on';
                app.GammaCheckBox_Shape.Enable = 'on';
                
            end
        end

        % Value changed function: NormalCheckBox_Shape
        function NormalCheckBox_ShapeValueChanged(app, event)
            if app.NormalCheckBox_Shape.Value
                app.UniformCheckBox_Shape.Enable = 'off';
                app.GammaCheckBox_Shape.Enable = 'off';
                app.GEV_SettingsMatrix{1,3} = 'norm';
            else
                app.UniformCheckBox_Shape.Enable = 'on';
                app.GammaCheckBox_Shape.Enable = 'on';
                
            end
        end

        % Value changed function: GammaCheckBox_Shape
        function GammaCheckBox_ShapeValueChanged(app, event)
            if app.GammaCheckBox_Shape.Value
                app.UniformCheckBox_Shape.Enable = 'off';
                app.NormalCheckBox_Shape.Enable = 'off';
                app.GEV_SettingsMatrix{1,3} = 'gam';
            else
                app.UniformCheckBox_Shape.Enable = 'on';
                app.NormalCheckBox_Shape.Enable = 'on';
                
            end
        end

        % Value changed function: Location_Params1
        function Location_Params1ValueChanged(app, event)
           app.GEV_SettingsMatrix{2,1} = str2double(app.Location_Params1.Value);
            
        end

        % Value changed function: Location_Params2
        function Location_Params2ValueChanged(app, event)
           app.GEV_SettingsMatrix{3,1} = str2double(app.Location_Params2.Value);
            
        end

        % Value changed function: Scale_Params1
        function Scale_Params1ValueChanged(app, event)
           app.GEV_SettingsMatrix{2,2} = str2double(app.Scale_Params1.Value);
            
        end

        % Value changed function: Scale_Params2
        function Scale_Params2ValueChanged(app, event)
           app.GEV_SettingsMatrix{3,2} = str2double(app.Scale_Params2.Value);
            
        end

        % Value changed function: Shape_Params1
        function Shape_Params1ValueChanged(app, event)
             app.GEV_SettingsMatrix{2,3} = str2double(app.Shape_Params1.Value);
            
        end

        % Value changed function: Shape_Params2
        function Shape_Params2ValueChanged(app, event)
            app.GEV_SettingsMatrix{3,3} = str2double(app.Shape_Params2.Value);
            
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
             assignin('base', 'GEV_SettingsMatrix', app.GEV_SettingsMatrix);
             uiresume(app.UIFigure);
             delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Step3PriorInformationGEVPanel = uipanel(app.UIFigure);
            app.Step3PriorInformationGEVPanel.Title = 'Step 3: Prior Information - GEV';
            app.GridLayout5 = uigridlayout(app.Step3PriorInformationGEVPanel);
            app.LocationPanel = uipanel(app.GridLayout5);
            app.LocationPanel.Title = 'Location';
            app.GridLayout = uigridlayout(app.LocationPanel);
            app.PriorDistributionPanel_Location = uipanel(app.GridLayout);
            app.PriorDistributionPanel_Location.Title = 'Prior Distribution';
            app.GridLayout2 = uigridlayout(app.PriorDistributionPanel_Location);
            app.UniformCheckBox_Location = uicheckbox(app.GridLayout2);
            app.UniformCheckBox_Location.ValueChangedFcn = createCallbackFcn(app, @UniformCheckBox_LocationValueChanged, true);
            app.UniformCheckBox_Location.Text = 'Uniform';
            app.NormalCheckBox_Location = uicheckbox(app.GridLayout2);
            app.NormalCheckBox_Location.ValueChangedFcn = createCallbackFcn(app, @NormalCheckBox_LocationValueChanged, true);
            app.NormalCheckBox_Location.Text = 'Normal';
            app.GammaCheckBox_Location = uicheckbox(app.GridLayout2);
            app.GammaCheckBox_Location.ValueChangedFcn = createCallbackFcn(app, @GammaCheckBox_LocationValueChanged, true);
            app.GammaCheckBox_Location.Text = 'Gamma';
            app.PriorParametersPanel_Location = uipanel(app.GridLayout);
            app.PriorParametersPanel_Location.Title = 'Prior Parameters';
            app.GridLayout3 = uigridlayout(app.PriorParametersPanel_Location);
            app.Location_Params1 = uieditfield(app.GridLayout3, 'text');
            app.Location_Params1.ValueChangedFcn = createCallbackFcn(app, @Location_Params1ValueChanged, true);
            app.Location_Params2 = uieditfield(app.GridLayout3, 'text');
            app.Location_Params2.ValueChangedFcn = createCallbackFcn(app, @Location_Params2ValueChanged, true);
            app.ScalePanel = uipanel(app.GridLayout5);
            app.ScalePanel.Title = 'Scale';
            app.Grid_Scale = uigridlayout(app.ScalePanel);
            app.PriorDistributionPanel_Scale = uipanel(app.Grid_Scale);
            app.PriorDistributionPanel_Scale.Title = 'Prior Distribution';
            app.GridLayout2_2 = uigridlayout(app.PriorDistributionPanel_Scale);
            app.UniformCheckBox_Scale = uicheckbox(app.GridLayout2_2);
            app.UniformCheckBox_Scale.ValueChangedFcn = createCallbackFcn(app, @UniformCheckBox_ScaleValueChanged, true);
            app.UniformCheckBox_Scale.Text = 'Uniform';
            app.NormalCheckBox_Scale = uicheckbox(app.GridLayout2_2);
            app.NormalCheckBox_Scale.ValueChangedFcn = createCallbackFcn(app, @NormalCheckBox_ScaleValueChanged, true);
            app.NormalCheckBox_Scale.Text = 'Normal';
            app.GammaCheckBox_Scale = uicheckbox(app.GridLayout2_2);
            app.GammaCheckBox_Scale.ValueChangedFcn = createCallbackFcn(app, @GammaCheckBox_ScaleValueChanged, true);
            app.GammaCheckBox_Scale.Text = 'Gamma';
            app.PriorParametersPanel_Scale = uipanel(app.Grid_Scale);
            app.PriorParametersPanel_Scale.Title = 'Prior Parameters';
            app.GridLayout3_2 = uigridlayout(app.PriorParametersPanel_Scale);
            app.Scale_Params1 = uieditfield(app.GridLayout3_2, 'text');
            app.Scale_Params1.ValueChangedFcn = createCallbackFcn(app, @Scale_Params1ValueChanged, true);
            app.Scale_Params2 = uieditfield(app.GridLayout3_2, 'text');
            app.Scale_Params2.ValueChangedFcn = createCallbackFcn(app, @Scale_Params2ValueChanged, true);
            app.ShapePanel = uipanel(app.GridLayout5);
            app.ShapePanel.Title = 'Shape';
            app.Grid_Shape = uigridlayout(app.ShapePanel);
            app.Prior_Distribution_Shape = uipanel(app.Grid_Shape);
            app.Prior_Distribution_Shape.Title = 'Prior Distribution';
            app.Grid_Shape_Distribution = uigridlayout(app.Prior_Distribution_Shape);
            app.UniformCheckBox_Shape = uicheckbox(app.Grid_Shape_Distribution);
            app.UniformCheckBox_Shape.ValueChangedFcn = createCallbackFcn(app, @UniformCheckBox_ShapeValueChanged, true);
            app.UniformCheckBox_Shape.Text = 'Uniform';
            app.NormalCheckBox_Shape = uicheckbox(app.Grid_Shape_Distribution);
            app.NormalCheckBox_Shape.ValueChangedFcn = createCallbackFcn(app, @NormalCheckBox_ShapeValueChanged, true);
            app.NormalCheckBox_Shape.Text = 'Normal';
            app.GammaCheckBox_Shape = uicheckbox(app.Grid_Shape_Distribution);
            app.GammaCheckBox_Shape.ValueChangedFcn = createCallbackFcn(app, @GammaCheckBox_ShapeValueChanged, true);
            app.GammaCheckBox_Shape.Text = 'Gamma';
            app.Prior_Param_Shape = uipanel(app.Grid_Shape);
            app.Prior_Param_Shape.Title = 'Prior Parameters';
            app.Grid_Shape_Param = uigridlayout(app.Prior_Param_Shape);
            app.Shape_Params1 = uieditfield(app.Grid_Shape_Param, 'text');
            app.Shape_Params1.ValueChangedFcn = createCallbackFcn(app, @Shape_Params1ValueChanged, true);
            app.Shape_Params2 = uieditfield(app.Grid_Shape_Param, 'text');
            app.Shape_Params2.ValueChangedFcn = createCallbackFcn(app, @Shape_Params2ValueChanged, true);
            app.ContinueButton = uibutton(app.GridLayout5, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = third_gev

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end