classdef third_gp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Step3PriorInformationGPPanel  matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        ContinueButton                matlab.ui.control.Button
        ShapePanel                    matlab.ui.container.Panel
        GridLayout6_2                 matlab.ui.container.GridLayout
        PriorParametersPanel_2        matlab.ui.container.Panel
        GridLayout3_2                 matlab.ui.container.GridLayout
        ShapeParam2EditField          matlab.ui.control.EditField
        ShapeParam1EditField          matlab.ui.control.EditField
        PriorDistributionPanel_2      matlab.ui.container.Panel
        GridLayout2_2                 matlab.ui.container.GridLayout
        ShapeGammaCheckBox            matlab.ui.control.CheckBox
        ShapeNormalCheckBox           matlab.ui.control.CheckBox
        ShapeUniformCheckBox          matlab.ui.control.CheckBox
        ScalePanel                    matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        PriorParametersPanel          matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        ScaleParam2EditField          matlab.ui.control.EditField
        ScaleParam1EditField          matlab.ui.control.EditField
        PriorDistributionPanel        matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        ScaleGammaCheckBox            matlab.ui.control.CheckBox
        ScaleNormalCheckBox           matlab.ui.control.CheckBox
        ScaleUniformCheckBox          matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        MetricName   % Description
        GP_SettingsMatrix
    end
     

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize the 3x3 settings matrix with default values
            % Column 1: Location (NaN), Column 2: Scale, Column 3: Shape
            app.GP_SettingsMatrix = { ...
              NaN, 'Normal', 'norm'; ...  % Row 1: Distribution Types
              NaN, 0,        0;        ...  % Row 2: Parameter 1
              NaN, 1000,     0.3       ...  % Row 3: Parameter 2
            };
            app.UIFigure.Name ="TRUE";
            
            % --- Set UI components to match default matrix values ---
            % Scale (populates from Column 2)
            app.ScaleNormalCheckBox.Value = true;
            app.ScaleUniformCheckBox.Enable = 'off';
            app.ScaleGammaCheckBox.Enable = 'off';
            app.ScaleParam1EditField.Value = num2str(app.GP_SettingsMatrix{2, 2});
            app.ScaleParam2EditField.Value = num2str(app.GP_SettingsMatrix{3, 2});
            
            % Shape (populates from Column 3)
            app.ShapeNormalCheckBox.Value = true;
            app.ShapeUniformCheckBox.Enable = 'off';
            app.ShapeGammaCheckBox.Enable = 'off';
            app.ShapeParam1EditField.Value = num2str(app.GP_SettingsMatrix{2, 3});
            app.ShapeParam2EditField.Value = num2str(app.GP_SettingsMatrix{3, 3});
            
            % Wait for user to click "Continue" or close the window
            uiwait(app.UIFigure);
        end

        % Callback function
        function ScaleUniformCheckBoxValueChanged(app, event)
             if app.ScaleUniformCheckBox.Value
                app.ScaleNormalCheckBox.Enable = 'off';
                app.ScaleGammaCheckBox.Enable = 'off';
                app.GP_SettingsMatrix{1, 2} = 'unif';
            else
                app.ScaleNormalCheckBox.Enable = 'on';
                app.ScaleGammaCheckBox.Enable = 'on';
            end
        end

        % Callback function
        function ScaleNormalCheckBoxValueChanged(app, event)
            if app.ScaleNormalCheckBox.Value
                app.ScaleUniformCheckBox.Enable = 'off';
                app.ScaleNormalCheckBox.Enable = 'off';
                app.GP_SettingsMatrix{1, 2} = 'norm';
            else
                app.ScaleUniformCheckBox.Enable = 'on';
                app.ScaleGammaCheckBox.Enable = 'on';
            end
        end

        % Callback function
        function ScaleGammaCheckBoxValueChanged(app, event)
            app.GP_SettingsMatrix{2, 2} = str2double(app.ScaleParam1EditField.Value);
      
        end

        % Callback function
        function ScaleParam1EditFieldValueChanged(app, event)
            app.GP_SettingsMatrix{3, 2} = str2double(app.ScaleParam2EditField.Value);
        
        end

        % Callback function
        function ScaleParam2EditFieldValueChanged(app, event)
         app.GP_SettingsMatrix{3, 2} = str2double(app.ScaleParam2EditField.Value);
       
        end

        % Callback function
        function ShapeUniformCheckBoxValueChanged(app, event)
            if app.ShapeUniformCheckBox.Value
                app.ShapeNormalCheckBox.Enable = 'off';
                app.ShapeGammaCheckBox.Enable = 'off';
                app.GP_SettingsMatrix{1, 3} = 'unif';
            else
                app.ShapeNormalCheckBox.Enable = 'on';
                app.ShapeGammaCheckBox.Enable = 'on';
            end
        end

        % Callback function
        function ShapeNormalCheckBoxValueChanged(app, event)
             if app.ShapeNormalCheckBox.Value
                app.ShapeUniformCheckBox.Enable = 'off';
                app.ShapeGammaCheckBox.Enable = 'off';
                app.GP_SettingsMatrix{1, 3} = 'norm';
            else
                app.ShapeUniformCheckBox.Enable = 'on';
                app.ShapeGammaCheckBox.Enable = 'on';
            end
        end

        % Callback function
        function ShapeGammaCheckBoxValueChanged(app, event)
            if app.ShapeGammaCheckBox.Value
                app.ShapeUniformCheckBox.Enable = 'off';
                app.ShapeNormalCheckBox.Enable = 'off';
                app.GP_SettingsMatrix{1, 3} = 'gam';
            else
                app.ShapeUniformCheckBox.Enable = 'on';
                app.ShapeNormalCheckBox.Enable = 'on';
            end
        end

        % Callback function
        function ShapeParam1EditFieldValueChanged(app, event)
           app.GP_SettingsMatrix{2, 3} = str2double(app.ShapeParam1EditField.Value);
  
        end

        % Callback function
        function ShapeParam2EditFieldValueChanged(app, event)
             app.GP_SettingsMatrix{3, 3} = str2double(app.ShapeParam2EditField.Value);
   
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            assignin('base', 'GP_Settings_Matrix', app.GP_SettingsMatrix);
            uiresume(app.UIFigure);
            delete(app);
        end

        % Value changed function: ScaleGammaCheckBox
        function ScaleGammaCheckBoxValueChanged2(app, event)
           if app.ScaleGammaCheckBox.Value
                app.ScaleUniformCheckBox.Enable = 'off';
                app.ScaleNormalCheckBox.Enable = 'off';
                app.GP_SettingsMatrix{1, 2} = 'gam';
            else
                app.ScaleUniformCheckBox.Enable = 'on';
                app.ScaleNormalCheckBox.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Step3PriorInformationGPPanel = uipanel(app.UIFigure);
            app.Step3PriorInformationGPPanel.Title = 'Step 3: Prior Information - GP';
            app.GridLayout = uigridlayout(app.Step3PriorInformationGPPanel);
            app.ScalePanel = uipanel(app.GridLayout);
            app.ScalePanel.Title = 'Scale';
            app.GridLayout6 = uigridlayout(app.ScalePanel);
            app.PriorDistributionPanel = uipanel(app.GridLayout6);
            app.PriorDistributionPanel.Title = 'Prior Distribution';
            app.GridLayout2 = uigridlayout(app.PriorDistributionPanel);
            app.ScaleUniformCheckBox = uicheckbox(app.GridLayout2);
            app.ScaleUniformCheckBox.Text = 'Uniform';
            app.ScaleNormalCheckBox = uicheckbox(app.GridLayout2);
            app.ScaleNormalCheckBox.Text = 'Normal';
            app.ScaleGammaCheckBox = uicheckbox(app.GridLayout2);
            app.ScaleGammaCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaleGammaCheckBoxValueChanged2, true);
            app.ScaleGammaCheckBox.Text = 'Gamma';
            app.PriorParametersPanel = uipanel(app.GridLayout6);
            app.PriorParametersPanel.Title = 'Prior Parameters';
            app.GridLayout3 = uigridlayout(app.PriorParametersPanel);
            app.ScaleParam1EditField = uieditfield(app.GridLayout3, 'text');
            app.ScaleParam2EditField = uieditfield(app.GridLayout3, 'text');
            app.ShapePanel = uipanel(app.GridLayout);
            app.ShapePanel.Title = 'Shape';
            app.GridLayout6_2 = uigridlayout(app.ShapePanel);
            app.PriorDistributionPanel_2 = uipanel(app.GridLayout6_2);
            app.PriorDistributionPanel_2.Title = 'Prior Distribution';
            app.GridLayout2_2 = uigridlayout(app.PriorDistributionPanel_2);
            app.ShapeUniformCheckBox = uicheckbox(app.GridLayout2_2);
            app.ShapeUniformCheckBox.Text = 'Uniform';
            app.ShapeNormalCheckBox = uicheckbox(app.GridLayout2_2);
            app.ShapeNormalCheckBox.Text = 'Normal';
            app.ShapeGammaCheckBox = uicheckbox(app.GridLayout2_2);
            app.ShapeGammaCheckBox.Text = 'Gamma';
            app.PriorParametersPanel_2 = uipanel(app.GridLayout6_2);
            app.PriorParametersPanel_2.Title = 'Prior Parameters';
            app.GridLayout3_2 = uigridlayout(app.PriorParametersPanel_2);
            app.ShapeParam1EditField = uieditfield(app.GridLayout3_2, 'text');
            app.ShapeParam2EditField = uieditfield(app.GridLayout3_2, 'text');
            app.ContinueButton = uibutton(app.GridLayout, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = third_gp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end