classdef third_lognormal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Step3PriorInformationLognormalPanel  matlab.ui.container.Panel
        GridLayout5               matlab.ui.container.GridLayout
        ContinueButton            matlab.ui.control.Button
        ScalePanel                matlab.ui.container.Panel
        GridLayout6               matlab.ui.container.GridLayout
        PriorParametersPanel_2    matlab.ui.container.Panel
        GridLayout3_2             matlab.ui.container.GridLayout
        EditField_4               matlab.ui.control.EditField
        EditField_3               matlab.ui.control.EditField
        PriorDistributionPanel_2  matlab.ui.container.Panel
        GridLayout2_2             matlab.ui.container.GridLayout
        GammaCheckBox_2           matlab.ui.control.CheckBox
        NormalCheckBox_2          matlab.ui.control.CheckBox
        UniformCheckBox_2         matlab.ui.control.CheckBox
        LocationPanel             matlab.ui.container.Panel
        GridLayout                matlab.ui.container.GridLayout
        PriorParametersPanel      matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        EditField_2               matlab.ui.control.EditField
        EditField                 matlab.ui.control.EditField
        PriorDistributionPanel    matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        GammaCheckBox             matlab.ui.control.CheckBox
        NormalCheckBox            matlab.ui.control.CheckBox
        UniformCheckBox           matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        MetricName   % Description
        Lognormal_SettingsMatrix 
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lognormal_SettingsMatrix = { ...
              'norm', 'norm', NaN; ...  % Row 1: Distribution Types
              0,        0,        NaN; ...  % Row 2: Parameter 1
              1000,     1000,      NaN  ...  % Row 3: Parameter 2
            };
            app.UIFigure.Name ="TRUE";
            % --- Set UI components to match default matrix values ---
            % Location (populates from Column 1)
            app.NormalCheckBox.Value = true;
            app.UniformCheckBox.Enable = 'off';
            app.GammaCheckBox.Enable = 'off';
            app.EditField.Value = num2str(app.Lognormal_SettingsMatrix{2, 1});
            app.EditField_2.Value = num2str(app.Lognormal_SettingsMatrix{3, 1});
            
            % Scale (populates from Column 2)
            app.NormalCheckBox_2.Value = true;
            app.UniformCheckBox_2.Enable = 'off';
            app.GammaCheckBox_2.Enable = 'off';
            app.EditField_3.Value = num2str(app.Lognormal_SettingsMatrix{2, 2});
            app.EditField_4.Value = num2str(app.Lognormal_SettingsMatrix{3, 2});
            
            % Wait for user to click "Continue" or close the window
            uiwait(app.UIFigure);
        end

        % Value changed function: UniformCheckBox
        function UniformCheckBoxValueChanged(app, event)
            if app.UniformCheckBox.Value
                app.NormalCheckBox.Enable = 'off';
                app.GammaCheckBox.Enable = 'off';
                app.Lognormal_SettingsMatrix{1, 1} = 'unif';
            else
                app.NormalCheckBox.Enable = 'on';
                app.GammaCheckBox.Enable = 'on';
            end
            
        end

        % Value changed function: NormalCheckBox
        function NormalCheckBoxValueChanged(app, event)
             if app.NormalCheckBox.Value
                app.UniformCheckBox.Enable = 'off';
                app.GammaCheckBox.Enable = 'off';
                app.Lognormal_SettingsMatrix{1, 1} = 'norm';
            else
                app.UniformCheckBox.Enable = 'on';
                app.GammaCheckBox.Enable = 'on';
            end
        end

        % Value changed function: GammaCheckBox
        function GammaCheckBoxValueChanged(app, event)
            if app.GammaCheckBox.Value
                app.UniformCheckBox.Enable = 'off';
                app.NormalCheckBox.Enable = 'off';
                app.Lognormal_SettingsMatrix{1, 1} = 'gam';
            else
                app.UniformCheckBox.Enable = 'on';
                app.NormalCheckBox.Enable = 'on';
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.Lognormal_SettingsMatrix{2, 1} = str2double(app.EditField.Value);
       
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.Lognormal_SettingsMatrix{3, 1} = str2double(app.EditField_2.Value);
    
        end

        % Value changed function: UniformCheckBox_2
        function UniformCheckBox_2ValueChanged(app, event)
            if app.UniformCheckBox_2.Value
                app.NormalCheckBox_2.Enable = 'off';
                app.GammaCheckBox_2.Enable = 'off';
                app.Lognormal_SettingsMatrix{1, 2} = 'unif';
            else
                app.NormalCheckBox_2.Enable = 'on';
                app.GammaCheckBox_2.Enable = 'on';
            end
        end

        % Value changed function: NormalCheckBox_2
        function NormalCheckBox_2ValueChanged(app, event)
            if app.NormalCheckBox_2.Value
                app.UniformCheckBox_2.Enable = 'off';
                app.GammaCheckBox_2.Enable = 'off';
                app.Lognormal_SettingsMatrix{1, 2} = 'norm';
            else
                app.UniformCheckBox_2.Enable = 'on';
                app.GammaCheckBox_2.Enable = 'on';
            end
        end

        % Value changed function: GammaCheckBox_2
        function GammaCheckBox_2ValueChanged(app, event)
             if app.GammaCheckBox_2.Value
                app.UniformCheckBox_2.Enable = 'off';
                app.NormalCheckBox_2.Enable = 'off';
                app.Lognormal_SettingsMatrix{1, 2} = 'gam';
            else
                app.UniformCheckBox_2.Enable = 'on';
                app.NormalCheckBox_2.Enable = 'on';
            end
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
             app.Lognormal_SettingsMatrix{2, 2} = str2double(app.EditField_3.Value);
      
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
              app.Lognormal_SettingsMatrix{3, 2} = str2double(app.EditField_4.Value);
   
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            assignin('base', 'Lognormal_Settings_Matrix', app.Lognormal_SettingsMatrix);
            uiresume(app.UIFigure);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Step3PriorInformationLognormalPanel = uipanel(app.UIFigure);
            app.Step3PriorInformationLognormalPanel.Title = 'Step 3: Prior Information - Lognormal';
            app.GridLayout5 = uigridlayout(app.Step3PriorInformationLognormalPanel);
            app.LocationPanel = uipanel(app.GridLayout5);
            app.LocationPanel.Title = 'Location';
            app.GridLayout = uigridlayout(app.LocationPanel);
            app.PriorDistributionPanel = uipanel(app.GridLayout);
            app.PriorDistributionPanel.Title = 'Prior Distribution';
            app.GridLayout2 = uigridlayout(app.PriorDistributionPanel);
            app.UniformCheckBox = uicheckbox(app.GridLayout2);
            app.UniformCheckBox.ValueChangedFcn = createCallbackFcn(app, @UniformCheckBoxValueChanged, true);
            app.UniformCheckBox.Text = 'Uniform';
            app.NormalCheckBox = uicheckbox(app.GridLayout2);
            app.NormalCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalCheckBoxValueChanged, true);
            app.NormalCheckBox.Text = 'Normal';
            app.GammaCheckBox = uicheckbox(app.GridLayout2);
            app.GammaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GammaCheckBoxValueChanged, true);
            app.GammaCheckBox.Text = 'Gamma';
            app.PriorParametersPanel = uipanel(app.GridLayout);
            app.PriorParametersPanel.Title = 'Prior Parameters';
            app.GridLayout3 = uigridlayout(app.PriorParametersPanel);
            app.EditField = uieditfield(app.GridLayout3, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.GridLayout3, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.ScalePanel = uipanel(app.GridLayout5);
            app.ScalePanel.Title = 'Scale';
            app.GridLayout6 = uigridlayout(app.ScalePanel);
            app.PriorDistributionPanel_2 = uipanel(app.GridLayout6);
            app.PriorDistributionPanel_2.Title = 'Prior Distribution';
            app.GridLayout2_2 = uigridlayout(app.PriorDistributionPanel_2);
            app.UniformCheckBox_2 = uicheckbox(app.GridLayout2_2);
            app.UniformCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @UniformCheckBox_2ValueChanged, true);
            app.UniformCheckBox_2.Text = 'Uniform';
            app.NormalCheckBox_2 = uicheckbox(app.GridLayout2_2);
            app.NormalCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @NormalCheckBox_2ValueChanged, true);
            app.NormalCheckBox_2.Text = 'Normal';
            app.GammaCheckBox_2 = uicheckbox(app.GridLayout2_2);
            app.GammaCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @GammaCheckBox_2ValueChanged, true);
            app.GammaCheckBox_2.Text = 'Gamma';
            app.PriorParametersPanel_2 = uipanel(app.GridLayout6);
            app.PriorParametersPanel_2.Title = 'Prior Parameters';
            app.GridLayout3_2 = uigridlayout(app.PriorParametersPanel_2);
            app.EditField_3 = uieditfield(app.GridLayout3_2, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.GridLayout3_2, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.ContinueButton = uibutton(app.GridLayout5, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = third_lognormal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end