classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Step1DataLoadingPanel     matlab.ui.container.Panel
        TotalSimulationsPanel     matlab.ui.container.Panel
        NumberofSimulationsLabel  matlab.ui.control.Label
        Simulationfield           matlab.ui.control.TextArea
        ContinueButton            matlab.ui.control.Button
        CovariatePanel            matlab.ui.container.Panel
        SelectDataPanel_3         matlab.ui.container.Panel
        TextArea_2                matlab.ui.control.TextArea
        BrowseButton_2            matlab.ui.control.Button
        SelectDataPanel_2         matlab.ui.container.Panel
        TextArea                  matlab.ui.control.TextArea
        BrowseButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        OBS % to store loaded data
        RUNspec %struct to hold model & covariate choices
        AMS
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.UIFigure.Name = "TRUE";
             uiwait(app.UIFigure);  % pause script until UIFigure is closed or resumed

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Changed prompt to reflect univariate data
            [Data_File, Data_Path] = uigetfile({'*.csv'}, 'Select the Data File');
            if isequal(Data_File, 0), return; end
            
            ExPath = fullfile(Data_Path, Data_File);
            app.TextArea.Value = ExPath;
            uialert(app.UIFigure, 'Main data loaded successfully.', 'Success');
            assignin("base","File_Path_AMS",ExPath);


            % 
            % 
            % try
            %     % Load the data file
            %     T = readtable(ExPath);
            %     % Store the data. Assumes first column is Year, second is Data.
            %     % If only one column, it will store that.
            %     app.AMS = T{:,:};
            % 
                
            %     
            % 
            % catch ME
            %     uialert(app.UIFigure, ['Error during data loading: ' ME.message], 'Error');
            % end
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
          
           % Ask for the covariate folder
            CovariateFolder = uigetdir('', 'Select Folder Containing Covariate CSV Files');
            
            if isequal(CovariateFolder, 0), return; end


            app.TextArea_2.Value = CovariateFolder;
            uialert(app.UIFigure, 'Covariate files loaded successfully.', 'Success');
            assignin("base","File_path_Covariates",CovariateFolder);
            % 
            % try
            %     covFiles = dir(fullfile(CovariateFolder, '*.csv'));
            %     if isempty(covFiles)
            %         uialert(app.UIFigure, 'No .csv files found in the selected folder.', 'File Error');
            %         return;
            %     end
            % 
            %     covStruct = struct();
            %     for i = 1:numel(covFiles)
            %         filePath = fullfile(CovariateFolder, covFiles(i).name);
            %         [~, name, ~] = fileparts(filePath);
            %         covStruct.(name) = readmatrix(filePath);
            %     end
            % 
            %     % CRITICAL FIX: Store the loaded structure in an app property
            %     app.RUNspec.Covariates = covStruct;
            % 
            %     % Update UI to show the folder was selected
            %     
            % 
            % catch ME
            %    uialert(app.UIFigure, ['Error during Folder loading: ' ME.message], 'Error');
            % end
            % 
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            %  if isempty(app.AMS)
            %     uialert(app.UIFigure, 'Please select the main data (AMS) file before continuing.', 'Error');
            %     return;
            % end
            % if ~app.StationaryCheckBox.Value && ~app.NonStationaryCheckBox.Value
            %     uialert(app.UIFigure, 'Please select a model type (Stationary or Non-Stationary).', 'Error');
            %     return;
            % end
            % if app.NonStationaryCheckBox.Value && app.UserDefinedCheckBox.Value
            %     if isempty(app.RUNspec.Covariates)
            %          uialert(app.UIFigure, 'Please select the covariate folder for the non-stationary model.', 'Error');
            %          return;
            %     end
            % end
            % 
            % % --- If validation passes, proceed with data processing ---
            % try
            %     % Retrieve data from the app properties
            %     covStruct = app.RUNspec.Covariates;
            % 
            %     % Get common years between AMS and all covariates
            %     common_years = unique(app.AMS(:, 1));
            %     if ~isempty(covStruct)
            %         covNames = fieldnames(covStruct);
            %         for i = 1:numel(covNames)
            %             mat = covStruct.(covNames{i});
            %             common_years = intersect(common_years, mat(:, 1));
            %         end
            %     end
            % 
            %     % Collect and combine data for common years
            %     combined_data = app.AMS(ismember(app.AMS(:, 1), common_years), 2);
            % 
            %     if ~isempty(covStruct)
            %         for i = 1:numel(covNames)
            %             mat = covStruct.(covNames{i});
            %             covariate_col = mat(ismember(mat(:, 1), common_years), 2);
            %             combined_data = [combined_data, covariate_col];
            %         end
            %     end
            % 
            %     Time = (1:size(combined_data, 1))';
            % 
            %     % Store processed data in a structure
            %     processedData.Years = common_years;
            %     processedData.PF = combined_data(:, 1);
            %     if size(combined_data, 2) > 1
            %         processedData.Covariates = combined_data(:, 2:end);
            %     else
            %         processedData.Covariates = [];
            %     end
            %     processedData.Time = Time;
            %     processedData.ChangePoint = NaN;
            % 
            %     % --- Package data for the second app ---
            %     dataToPass.Observations = processedData;
            %     dataToPass.Settings = app.RUNspec;
            % 
            %     if app.StationaryCheckBox.Value
            %         dataToPass.ModelType = 'Stationary';
            %     else
            %         dataToPass.ModelType = 'Non-Stationary';
            %     end
            % 
            % 
            % 
            %     app2 = secondWindow(); 
            % 
            %     app2.setup(dataToPass);
            % 
                
                % Close the current window
                uiresume(app.UIFigure);
                delete(app.UIFigure);
        
            % catch ME
            %     uialert(app.UIFigure, ['Error during data processing: ' ME.message], 'Processing Error');
            % end
        
        end

        % Value changed function: Simulationfield
        function SimulationfieldValueChanged(app, event)
            Total_simulations = str2double(app.Simulationfield.Value);

            if isnan(Total_simulations)
                uialert(app.UIFigure, ...
                    'Please enter a valid integer number of simulations.', ...
                    'Invalid Input');
                return;
            end
        
            Total_simulations = round(Total_simulations);
            assignin("base", "Total_simulations", Total_simulations);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Step1DataLoadingPanel = uipanel(app.UIFigure);
            app.Step1DataLoadingPanel.Title = ' Step 1: Data Loading';
            app.SelectDataPanel_2 = uipanel(app.Step1DataLoadingPanel);
            app.SelectDataPanel_2.Title = 'Select Data';
            app.BrowseButton = uibutton(app.SelectDataPanel_2, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.TextArea = uitextarea(app.SelectDataPanel_2);
            app.CovariatePanel = uipanel(app.Step1DataLoadingPanel);
            app.CovariatePanel.Title = 'Covariate';
            app.SelectDataPanel_3 = uipanel(app.CovariatePanel);
            app.SelectDataPanel_3.Title = 'Select Data';
            app.BrowseButton_2 = uibutton(app.SelectDataPanel_3, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.TextArea_2 = uitextarea(app.SelectDataPanel_3);
            app.ContinueButton = uibutton(app.Step1DataLoadingPanel, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.TotalSimulationsPanel = uipanel(app.Step1DataLoadingPanel);
            app.TotalSimulationsPanel.Title = 'Total Simulations';
            app.Simulationfield = uitextarea(app.TotalSimulationsPanel);
            app.Simulationfield.ValueChangedFcn = createCallbackFcn(app, @SimulationfieldValueChanged, true);
            app.NumberofSimulationsLabel = uilabel(app.TotalSimulationsPanel);
            app.NumberofSimulationsLabel.Text = 'Number of Simulations :';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end