classdef secondWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Step2BestFitBestCovariatePanel  matlab.ui.container.Panel
        ContinueButton                  matlab.ui.control.Button
        SelectaDistributionPanel        matlab.ui.container.Panel
        PeakFlowPFPanel                 matlab.ui.container.Panel
        pf_weibull_check                matlab.ui.control.CheckBox
        pf_normal_check                 matlab.ui.control.CheckBox
        pf_logistics_check              matlab.ui.control.CheckBox
        pf_gumbel_check                 matlab.ui.control.CheckBox
        BestCovariateCombinationTextArea  matlab.ui.control.TextArea
        BestCovariateCombinationLabel   matlab.ui.control.Label
        BestFitTextArea                 matlab.ui.control.TextArea
        BestFitTextAreaLabel            matlab.ui.control.Label
        pf_lognormal_check              matlab.ui.control.CheckBox
        pf_gp_check                     matlab.ui.control.CheckBox
        pf_gev_check                    matlab.ui.control.CheckBox
        pf_gamma_check                  matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
    end

        
        properties (Access = public)
         ReceivedData
         BestFitPF
          
        end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = "TRUE";   
            uiwait(app.UIFigure);  
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
                % First, check if any data was received from the main window.
                % We check the property that the first app actually populates.


                try
                    % --- Step 1: Get data DIRECTLY from app.ReceivedData ---
                    % This is the corrected data access
                  
                    if evalin('base', "exist('DATA', 'var')")
                        % This code runs ONLY if 'DATA' exists in the workspace.
                        DATA = evalin('base', 'DATA');
                        disp('Successfully loaded "DATA" from the base workspace.');
                    else
                        % This code runs if 'DATA' is NOT found.
                        warning('Variable "DATA" not found in the base workspace. Cannot proceed.');
                        % You might want to assign a default value or stop the script here.
                        % For example: error('Required variable "DATA" is missing.');
                    end
                    Years = DATA(:,1);
                    PF = DATA(:,2);
                    Covariate = zscore(DATA(:,3:end-1)); % Standardized covariates
                    Covariate1 = Covariate;
                    % Covariate = app.ReceivedData.Observations.Covariates;
                   

                    %% Model setup

                    numVariables = size(Covariate, 2);
                    allCombinations = cell(1, numVariables);
                    for variableIndex = 1:numVariables
                        allCombinations{variableIndex} = nchoosek(1:numVariables, variableIndex);
                    end


                    PARA_FINAL_PF1(1:25005, 1:15, 1:63) = 0;


                    selected_distributions = {};
                    if app.pf_gev_check.Value, selected_distributions{end+1} = 'gev'; end
                    if app.pf_gp_check.Value, selected_distributions{end+1} = 'gp'; end
                    if app.pf_gamma_check.Value, selected_distributions{end+1} = 'gamma'; end
                    if app.pf_lognormal_check.Value, selected_distributions{end+1} = 'lognormal'; end
                    if app.pf_normal_check.Value, selected_distributions{end+1}="normal"; end
                    if app.pf_gumbel_check.Value, selected_distributions{end+1}="ev"; end
                    if app.pf_logistics_check.Value, selected_distributions{end+1}="logistic"; end
                    if app.pf_weibull_check.Value, selected_distributions{end+1}="weibull"; end
                    
                    if isempty(selected_distributions)
                        uialert(app.UIFigure, 'Please select at least one distribution.', 'Input Required');
                        return;
                    end
                    
                    % Run fitmethis2 (tests all 4 distributions)
                    all_results_PF = fitmethis2_Updated(PF,selected_distributions);
                    all_results_PF = all_results_PF(~strcmpi({all_results_PF.name}, 'gamma'));
                    filtered_results = all_results_PF;
                    
                    if isempty(filtered_results)
                        uialert(app.UIFigure, 'No valid results for selected distributions.', 'Error');
                        return;
                    end
                    
                    % Find best among selected distributions
                    % [~, best_fit_index] = min([filtered_results.aic]);
                    % best_fit_PF = filtered_results(best_fit_index).name;
                    
                    best_fit_PF=filtered_results(1).name;
    
                        
                    Total_simulations = evalin("base","Total_simulations"); % Total number of times DIC should be calculated for each model

                    
                    c = parcluster('local');
                    parpool(c, c.NumWorkers);
                    disp('Simulations are running....')
                    
                    parfor simulation = 1:Total_simulations
                        [DIC_PF1(:,simulation),~,PARA_FINAL_PF] = model_evaluator2_Updated(numVariables, allCombinations, Covariate1, PF, best_fit_PF);
                        PARA_FINAL_PF1 = PARA_FINAL_PF1 + PARA_FINAL_PF;
                    
                        disp(['Simulation ' num2str(simulation) ' done']);
                    end
                    
                    PARA_FINAL_PF1 = PARA_FINAL_PF1 ./ Total_simulations;
                    DIC_PF1_average = mean(DIC_PF1, 2);
                    
                    delete(gcp('nocreate'));

                    n = size(Covariate1,2);
                    combinations = {};
                    index = 1;
                    for k = 1:n
                        combs = nchoosek(1:n, k);
                        for i = 1:size(combs,1)
                            combinations{index} = combs(i,:);
                            index = index + 1;
                        end
                    end
                    
                    [~, BM_PF_index] = min(DIC_PF1_average);
                    Best_Covariate_PF = cell2mat(combinations(BM_PF_index));
                    Best_Model_PF = BM_PF_index;

                    
                    formattedString = num2str(Best_Covariate_PF);
                   
                    % Display the best covariate combination
                    app.BestCovariateCombinationTextArea.Value = formattedString;
                    
            
                    % --- Step 5: Display the final results ---
                    resultsText1 = sprintf('%s', best_fit_PF);
                   
                    if resultsText1 == "ev"
                        app.BestFitTextArea.Value = "Gumbel";
                    else
                        app.BestFitTextArea.Value = resultsText1;
                    end

                    % app.BestCovariateCombinationTextArea.Value = resultsText2;
                    

                    % Save results in app properties for later use
                    assignin("base","Best_Covariate_PF",Best_Covariate_PF);
                    assignin("base","Best_Model_PF",Best_Model_PF);
                    assignin("base","PARA_FINAL_PF1",PARA_FINAL_PF1);
                    assignin('base','PF', PF);
                    assignin('base','Covariate', Covariate);
                    assignin('base','best_fit_PF', best_fit_PF);
                    assignin("base","Years",Years);


                catch ME
                    uialert(app.UIFigure, ['An error occurred during analysis: ' ME.message], 'Analysis Error');
                end
                
                app.UIFigure.Pointer = 'arrow';
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
          % try
          %           % Open windows for Peak Flow
          %           switch lower(char(app.BestFitPF))
          %               case 'gev'
          %                   app1 = third_gev();
          %                   app1.setup('Peak Flow');
          %               case 'gp'
          %                   app1 = third_gp();
          %                   app1.setup('Peak Flow');
          %               case 'gamma'
          %                   app1 = third_gamma();
          %                   app1.setup('Peak Flow');
          %               case 'lognormal'
          %                   app1 = third_lognormal();
          %                   app1.setup('Peak Flow');
          %           end    
          % 
          %       catch ME
          %           uialert(app.UIFigure, ['Could not open one or more third windows: ' ME.message], 'Error');
          %  end
          % 
          % 

              uiresume(app.UIFigure);
              delete(app.UIFigure);
        
        end

        % Value changed function: pf_gamma_check
        function pf_gamma_checkValueChanged(app, event)
            value = app.pf_gamma_check.Value;
            
        end

        % Value changed function: pf_gev_check
        function pf_gev_checkValueChanged(app, event)
            value = app.pf_gev_check.Value;
            
        end

        % Value changed function: pf_gp_check
        function pf_gp_checkValueChanged(app, event)
            value = app.pf_gp_check.Value;
            
        end

        % Value changed function: pf_lognormal_check
        function pf_lognormal_checkValueChanged(app, event)
            value = app.pf_lognormal_check.Value;
            
        end

        % Value changed function: pf_weibull_check
        function pf_weibull_checkValueChanged(app, event)
            value = app.pf_weibull_check.Value;
            
        end

        % Value changed function: pf_normal_check
        function pf_normal_checkValueChanged(app, event)
            value = app.pf_normal_check.Value;
            
        end

        % Value changed function: pf_logistics_check
        function pf_logistics_checkValueChanged(app, event)
            value = app.pf_logistics_check.Value;
            
        end

        % Value changed function: pf_gumbel_check
        function pf_gumbel_checkValueChanged(app, event)
            value = app.pf_gumbel_check.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Step2BestFitBestCovariatePanel = uipanel(app.UIFigure);
            app.Step2BestFitBestCovariatePanel.Title = 'Step 2: Best Fit & Best Covariate';
            app.RunButton = uibutton(app.Step2BestFitBestCovariatePanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectaDistributionPanel = uipanel(app.Step2BestFitBestCovariatePanel);
            app.SelectaDistributionPanel.Title = 'Select a Distribution';
            app.PeakFlowPFPanel = uipanel(app.SelectaDistributionPanel);
            app.PeakFlowPFPanel.Title = 'Peak Flow (PF)';
            app.pf_gamma_check = uicheckbox(app.PeakFlowPFPanel);
            app.pf_gamma_check.ValueChangedFcn = createCallbackFcn(app, @pf_gamma_checkValueChanged, true);
            app.pf_gamma_check.Text = 'Gamma';
            app.pf_gev_check = uicheckbox(app.PeakFlowPFPanel);
            app.pf_gev_check.ValueChangedFcn = createCallbackFcn(app, @pf_gev_checkValueChanged, true);
            app.pf_gev_check.Text = 'Gev';
            app.pf_gp_check = uicheckbox(app.PeakFlowPFPanel);
            app.pf_gp_check.ValueChangedFcn = createCallbackFcn(app, @pf_gp_checkValueChanged, true);
            app.pf_gp_check.Text = 'GP';
            app.pf_lognormal_check = uicheckbox(app.PeakFlowPFPanel);
            app.pf_lognormal_check.ValueChangedFcn = createCallbackFcn(app, @pf_lognormal_checkValueChanged, true);
            app.pf_lognormal_check.Text = 'Lognormal';
            app.BestFitTextAreaLabel = uilabel(app.PeakFlowPFPanel);
            app.BestFitTextAreaLabel.Text = 'Best Fit:';
            app.BestFitTextArea = uitextarea(app.PeakFlowPFPanel);
            app.BestCovariateCombinationLabel = uilabel(app.PeakFlowPFPanel);
            app.BestCovariateCombinationLabel.Text = 'Best Covariate Combination:';
            app.BestCovariateCombinationTextArea = uitextarea(app.PeakFlowPFPanel);
            app.pf_gumbel_check = uicheckbox(app.PeakFlowPFPanel);
            app.pf_gumbel_check.ValueChangedFcn = createCallbackFcn(app, @pf_gumbel_checkValueChanged, true);
            app.pf_gumbel_check.Text = 'Gumbel';
            app.pf_logistics_check = uicheckbox(app.PeakFlowPFPanel);
            app.pf_logistics_check.ValueChangedFcn = createCallbackFcn(app, @pf_logistics_checkValueChanged, true);
            app.pf_logistics_check.Text = 'Logistic';
            app.pf_normal_check = uicheckbox(app.PeakFlowPFPanel);
            app.pf_normal_check.ValueChangedFcn = createCallbackFcn(app, @pf_normal_checkValueChanged, true);
            app.pf_normal_check.Text = 'Normal';
            app.pf_weibull_check = uicheckbox(app.PeakFlowPFPanel);
            app.pf_weibull_check.ValueChangedFcn = createCallbackFcn(app, @pf_weibull_checkValueChanged, true);
            app.pf_weibull_check.Text = 'Weibull';
            app.ContinueButton = uibutton(app.Step2BestFitBestCovariatePanel, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = secondWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end